classdef SOLINDAR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        SOLINDARPanel   matlab.ui.container.Panel
        CH1AnaButton    matlab.ui.control.StateButton
        CH1AnaButton_2  matlab.ui.control.StateButton
        CH1AnaButton_3  matlab.ui.control.StateButton
        PosicinX0Label  matlab.ui.control.Label
        PosicinY0Label  matlab.ui.control.Label
        Switch          matlab.ui.control.Switch
        LoggingLabel    matlab.ui.control.Label
        LoggingLamp     matlab.ui.control.Lamp
        Switch_2        matlab.ui.control.Switch
        CH1AnaLabel     matlab.ui.control.Label
        cmLabel         matlab.ui.control.Label
        AnguloLabel     matlab.ui.control.Label
        Label           matlab.ui.control.Label
        Gauge           matlab.ui.control.SemicircularGauge
    end

    
    properties (Access = private)
         % Serial
         s = serial('COM8')
         baudrate = 115200;
         buffersize = 4096;
         % General parameters
         window = 1008;
         fs = 2800;
         cha_ana
         cha_dig
         chb_dig
         cha_ana_k = 1;
         offset_cha = 0;
         threshold;
         alarm;
    end   
    
    methods (Access = private)
        % Callback para cuando se genera el evento de buffer lleno
        function readSerial(app)
            data = fread(app.s, app.buffersize, 'int8');
            [cha_ana_dat, cha_dig_dat, chb_dig_dat, blocksize] = unpack(app,data);
            
            % Verify threshold value
            if( app.alarm == true)
             if ( app.threshold <= max(cha_ana_dat))
                fwrite(app.s, 17,'int8');
                app.CH1AnaButton_3.Value = false;
                app.alarm = false;
             end
            end
            
            % Apply scale factor
            cha_ana_dat = double((cha_ana_dat + app.offset_cha)*app.cha_ana_k);
            
            % Remove DC component if the AC coupling is enable
            if app.CouplingCH1AnaSwitch.Value == 'AC'
                cha_ana_dat = cha_ana_dat - mean(cha_ana_dat)+ 127;
            end
            
            % Plot
            addpoints(app.cha_ana, 1:blocksize, cha_ana_dat);
        end
    
        function initSerial(app)
            app.s.BaudRate = app.baudrate;
            app.s.InputBufferSize = app.buffersize;
            app.s.BytesAvailableFcnCount = app.buffersize;      % Número de bytes en el buffer de entrada para generar evento
            app.s.BytesAvailableFcnMode = 'byte';
            app.s.BytesAvailableFcn = @(~,~)app.readSerial; 
        end
        
        function initAnimatedLines(app)
            % Create animatedlines filtroects
            app.cha_ana = animatedline(app.UIAxes, 'Color',[0.00,0.45,0.74]);          
            % Turn off the visibility in digitals channels by default
         
           % app.FilterONLamp.Enable = 'on';
          
            % Assign maximum numbers of point
            app.cha_ana.MaximumNumPoints = app.window;
            app.cha_dig.MaximumNumPoints = app.window;
            app.chb_dig.MaximumNumPoints = app.window;
            app.cha_ana.AlignVertexCenters = 'on';
        end
    
    function [cha_ana, cha_dig, chb_dig, blocksize] = unpack(app,data)
            databyte = int8(data);

            % Sync the data
            start = find(databyte > 0, 1);
            syncData = databyte(start+1:end-mod((app.buffersize-start),2));
            
            % Convert to unsigned
            syncData = typecast(syncData, 'uint8');
            
            % Resulting data block size
            blocksize = length(syncData)/2;
            
            byte0 = syncData(1:2:end);
            byte1 = syncData(2:2:end);
                        
            % Mask for analog channels
            Mask_Ana(1:blocksize,1) = uint8(15);
            
            cha_ana_l = bitand(byte0, Mask_Ana);
            cha_ana_h = bitand(byte1, Mask_Ana);         
            
            % Analog Channels
            cha_ana = uint8(cha_ana_h)*16 + uint8(cha_ana_l);
            
            % Mask for digital channels
            Mask_Dig_a(1:blocksize,1) = uint8(16);
            Mask_Dig_b(1:blocksize,1) = uint8(32);
            
            % Digital Channels
            cha_dig = bitand(byte1, Mask_Dig_a);
            chb_dig = bitand(byte1, Mask_Dig_b);
           
    end   
 end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initSerial;
            app.initAnimatedLines;
            
            fopen(app.s);    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.s);
            delete(app);
        end

        % Callback function
        function TimeKnobValueChanged(app, event)
            value = str2double(app.TimeKnob.Value);
            app.Label.Text = string(value);
            scaleMin = 1064;
            scaleMax = 10*value*app.fs + 1064;
            app.UIAxes.XLim = [scaleMin, scaleMax];
            app.UIAxes.XTick = linspace(scaleMin,scaleMax,11);
        end

        % Callback function
        function CH1KnobValueChanged(app, event)
            value = str2double(app.CH1Knob.Value);
            app.cmLabel.Text = string(value) + "V";
            app.cha_ana_k = 0.5/value;
        end

        % Value changed function: CH1AnaButton
        function CH1AnaButtonValueChanged(app, event)
            value = app.CH1AnaButton.Value;
            app.cha_ana.Visible = value;
        end

        % Callback function
        function MaxAmplitudeSliderValueChanged(app, event)
            app.threshold = app.MaxAmplitudeSlider.Value; 
            disp(app.threshold);
        end

        % Callback function
        function CH1AnaButton_2ValueChanged(app, event)
            value = app.CH1AnaButton_2.Value;
            if (value == true)
                fwrite(app.s, 16, 'int8');
                app.CH1AnaButton_2.Value = false;
            end
        end

        % Callback function
        function CH1AnaButton_3ValueChanged(app, event)
            app.alarm = app.CH1AnaButton_3.Value;
            if (app.alarm == true)
                app.CH1AnaButton_3.Value = false;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [1064 2064];
            app.UIAxes.YLim = [0 255];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [1032 1132 1232 1332 1432 1532 1632 1732 1832 1932 2032];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [0 25.5 51 76.5 102 127.5 153 178.5 204 229.5 255];
            app.UIAxes.YTickLabel = '';
            app.SOLINDARPanel = uipanel(app.UIFigure);
            app.SOLINDARPanel.Title = 'SOLINDAR';
            app.CH1AnaButton = uibutton(app.SOLINDARPanel, 'state');
            app.CH1AnaButton.ValueChangedFcn = createCallbackFcn(app, @CH1AnaButtonValueChanged, true);
            app.CH1AnaButton.Text = 'SONAR';
            app.CH1AnaButton.Value = true;
            app.CH1AnaButton_2 = uibutton(app.SOLINDARPanel, 'state');
            app.CH1AnaButton_2.Text = 'LIDAR';
            app.CH1AnaButton_2.Value = true;
            app.CH1AnaButton_3 = uibutton(app.SOLINDARPanel, 'state');
            app.CH1AnaButton_3.Text = 'Fusión';
            app.CH1AnaButton_3.Value = true;
            app.PosicinX0Label = uilabel(app.SOLINDARPanel);
            app.PosicinX0Label.Text = 'Posición X: 0';
            app.PosicinY0Label = uilabel(app.SOLINDARPanel);
            app.PosicinY0Label.Text = 'Posición Y: 0';
            app.Switch = uiswitch(app.SOLINDARPanel, 'slider');
            app.Switch_2 = uiswitch(app.SOLINDARPanel, 'slider');
            app.Switch_2.Items = {'', ''};
            app.Switch_2.Value = '';
            app.LoggingLamp = uilamp(app.SOLINDARPanel);
            app.LoggingLamp.Enable = 'off';
            app.LoggingLabel = uilabel(app.SOLINDARPanel);
            app.LoggingLabel.Text = 'Logging';
            app.CH1AnaLabel = uilabel(app.UIFigure);
            app.CH1AnaLabel.Text = 'Distancia';
            app.cmLabel = uilabel(app.UIFigure);
            app.cmLabel.Text = '1cm';
            app.AnguloLabel = uilabel(app.UIFigure);
            app.AnguloLabel.Text = 'Angulo';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0.01 °';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = SOLINDAR

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end